classdef GUI_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ModelParametersPanel       matlab.ui.container.Panel
        RmbarsmLEditField          matlab.ui.control.NumericEditField
        RmbarsmLLabel              matlab.ui.control.Label
        K1mbarEditField            matlab.ui.control.NumericEditField
        K1mbarLabel                matlab.ui.control.Label
        TOPmbarEditField           matlab.ui.control.NumericEditField
        TOPmbarLabel               matlab.ui.control.Label
        CLmLmbarEditField          matlab.ui.control.NumericEditField
        CLmLmbarLabel              matlab.ui.control.Label
        CfrcmLmbarEditField        matlab.ui.control.NumericEditField
        CfrcmLmbarEditFieldLabel   matlab.ui.control.Label
        FlowOptimizationPanel      matlab.ui.container.Panel
        QmLsEditField              matlab.ui.control.NumericEditField
        QmLsLabel                  matlab.ui.control.Label
        tinspsEditField            matlab.ui.control.NumericEditField
        tinspsLabel                matlab.ui.control.Label
        peakpressurembarEditField  matlab.ui.control.NumericEditField
        peakpressurembarLabel      matlab.ui.control.Label
        OptimizeFlowButton         matlab.ui.control.Button
        LoadDataButton             matlab.ui.control.Button
        CalibrateButton            matlab.ui.control.Button
        UIAxes_2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Patient_data % Description
    end
    
    
    methods (Access = private)
        
        function Calibrate(app)
            initial_guess=[12 45 0.03 1.7 0.02];
Q = app.QmLsEditField.Value;
tr = 0:0.01:app.tinspsEditField.Value;
N = 30;
SP = 0:0.5:14.5;

x = fminsearch(@(x) sse_Task3(x,tr,app.Patient_data,N,Q,SP), initial_guess);
app.TOPmbarEditField.Value = x(1);
app.CfrcmLmbarEditField.Value = x(2);
app.K1mbarEditField.Value =  x(3);
app.CLmLmbarEditField.Value = x(4);
app.RmbarsmLEditField.Value  = x(5);
  
 TOP = x(1);
 Cfrc = x(2);
 K = x(3);
 CL = x(4);
 R = x(5);
 y0=0;
 [tsol,ysol] = ode45(@(t,y) PRM(tr,y,TOP,N,Cfrc,K,CL,SP,Q),tr,y0);
palv = ysol;
paw_m = (R*Q) + palv;
plot (app.UIAxes_2,tsol,paw_m,'Color','r','Linewidth',2,'LineStyle','--')
plot (app.UIAxes_2,tsol,app.Patient_data,'Color','b','LineStyle','-')
legend(app.UIAxes_2,'Fitted Model','Measured Data')
grid (app.UIAxes_2,'on')
hold (app.UIAxes_2,'on')


        end
        
        function Optimizeflow(app)
             initial_guess=[12 45 0.03 1.7 0.02];
Q = app.QmLsEditField.Value;
tr = 0:0.01:app.tinspsEditField.Value;
N = 30;
SP = 0:0.5:14.5;

x = fminsearch(@(x) sse_Task3(x,tr,app.Patient_data,N,Q,SP), initial_guess);
app.TOPmbarEditField.Value = x(1);
app.CfrcmLmbarEditField.Value = x(2);
app.K1mbarEditField.Value =  x(3);
app.CLmLmbarEditField.Value = x(4);
app.RmbarsmLEditField.Value  = x(5);
  
 TOP = x(1);
 Cfrc = x(2);
 K = x(3);
 CL = x(4);
 R = x(5);
 paw = app.peakpressurembarEditField.Value;
initial_guess = [550];
t=0:0.1:2.5;
x = fminsearch(@(x) sse_Task4(x,t,paw,N,TOP,Cfrc,CL,K,R,SP),initial_guess);
app.QmLsEditField.Value = x(1);
            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [fileName,pathName] = uigetfile('.txt','Select Patient file');
            filePath = fullfile(pathName,fileName);
            app.Patient_data = load(filePath);
            app.CalibrateButton.Enable = 'on';
            app.OptimizeFlowButton.Enable = 'on';
            freq = 100;
            timescale = 1/100;
           time = 0:0.01:app.tinspsEditField.Value;
            plot(app.UIAxes_2,time,app.Patient_data)
         grid (app.UIAxes_2,'on')
hold (app.UIAxes_2,'on')

        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)

Calibrate(app)
        end

        % Button pushed function: OptimizeFlowButton
        function OptimizeFlowButtonPushed(app, event)
         Optimizeflow(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'airway pressure')
            xlabel(app.UIAxes_2, {'time [s]'; ''})
            ylabel(app.UIAxes_2, 'pressure [mbar]')
            zlabel(app.UIAxes_2, 'Z')
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.OptimizeFlowButton = uibutton(app.UIFigure, 'push');
            app.OptimizeFlowButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeFlowButtonPushed, true);
            app.OptimizeFlowButton.Enable = 'off';
            app.OptimizeFlowButton.Text = 'Optimize Flow';
            app.FlowOptimizationPanel = uipanel(app.UIFigure);
            app.FlowOptimizationPanel.Title = 'Flow Optimization';
            app.peakpressurembarLabel = uilabel(app.FlowOptimizationPanel);
            app.peakpressurembarLabel.Text = 'peak pressure [mbar]';
            app.peakpressurembarEditField = uieditfield(app.FlowOptimizationPanel, 'numeric');
            app.tinspsLabel = uilabel(app.FlowOptimizationPanel);
            app.tinspsLabel.Text = 'tinsp [s]';
            app.tinspsEditField = uieditfield(app.FlowOptimizationPanel, 'numeric');
            app.QmLsLabel = uilabel(app.FlowOptimizationPanel);
            app.QmLsLabel.Text = 'Q [mL/s]';
            app.QmLsEditField = uieditfield(app.FlowOptimizationPanel, 'numeric');
            app.QmLsEditField.ValueDisplayFormat = '%.1f';
            app.ModelParametersPanel = uipanel(app.UIFigure);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.CfrcmLmbarEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.CfrcmLmbarEditFieldLabel.Text = 'Cfrc [mL/mbar]';
            app.CfrcmLmbarEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.CfrcmLmbarEditField.ValueDisplayFormat = '%.2f';
            app.CLmLmbarLabel = uilabel(app.ModelParametersPanel);
            app.CLmLmbarLabel.Text = 'CL [mL/mbar]';
            app.CLmLmbarEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.CLmLmbarEditField.ValueDisplayFormat = '%.2f';
            app.TOPmbarLabel = uilabel(app.ModelParametersPanel);
            app.TOPmbarLabel.Text = 'TOP [mbar]';
            app.TOPmbarEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.TOPmbarEditField.ValueDisplayFormat = '%.2f';
            app.K1mbarLabel = uilabel(app.ModelParametersPanel);
            app.K1mbarLabel.Text = 'K [1/mbar]';
            app.K1mbarEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.K1mbarEditField.ValueDisplayFormat = '%.4f';
            app.RmbarsmLLabel = uilabel(app.ModelParametersPanel);
            app.RmbarsmLLabel.Text = 'R [mbar*s/mL]';
            app.RmbarsmLEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.RmbarsmLEditField.ValueDisplayFormat = '%.4f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end